classdef wing_profile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MEditFieldLabel               matlab.ui.control.Label
        mEditField                    matlab.ui.control.NumericEditField
        camberpropertiesLabel         matlab.ui.control.Label
        wingendproportionsLabel       matlab.ui.control.Label
        PEditField_2Label             matlab.ui.control.Label
        pEditField                    matlab.ui.control.NumericEditField
        XXEditField_2Label            matlab.ui.control.Label
        xxEditField                   matlab.ui.control.NumericEditField
        calculatewingButton           matlab.ui.control.Button
        wingPlot3D                    matlab.ui.control.UIAxes
        EndStartLabel                 matlab.ui.control.Label
        endEditField                  matlab.ui.control.NumericEditField
        wingPlotCamber                matlab.ui.control.UIAxes
        wingPlotEdge                  matlab.ui.control.UIAxes
        NACA4digitwinggeneratorLabel  matlab.ui.control.Label
        NumberofpointsLabel           matlab.ui.control.Label
        numberOfPointsEditField       matlab.ui.control.NumericEditField
        CalculationdensityLabel       matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function initPlots(app)
            
            % Create wingPlotCamber
            app.wingPlotCamber = uiaxes(app.UIFigure);
            title(app.wingPlotCamber, 'Camber')
            xlabel(app.wingPlotCamber, 'X')
            ylabel(app.wingPlotCamber, 'Y')
            app.wingPlotCamber.PlotBoxAspectRatio = [1 0.6 0.6];
            app.wingPlotCamber.XTick = [];
            app.wingPlotCamber.YTick = [];
            app.wingPlotCamber.Position = [341 280 300 205];
            ax=app.wingPlotCamber;
            % clf(app.wingPlot3D, 'reset')
            ax.XLim = [0 1];
            ax.YLim = [-.5 .5];
            ax.XTick = 0:0.2:1;
            ax.YTick = -.5:0.2:.5;
            ax.Color = 'k';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.GridColor = [1 1 0];

            % Create wingPlotEdge
            app.wingPlotEdge = uiaxes(app.UIFigure);
            title(app.wingPlotEdge, 'Edge')
            xlabel(app.wingPlotEdge, 'X')
            ylabel(app.wingPlotEdge, 'Y')
            app.wingPlotEdge.PlotBoxAspectRatio = [1 0.889328063241107 0.889328063241107];
            app.wingPlotEdge.Position = [341 1 300 280];
            ax=app.wingPlotEdge;
            % clf(app.wingPlotEdge, 'reset')
            ax.XLim = [0 10];
            ax.YLim = [0 1];
            ax.XTick = 0:1:10;
            ax.YTick = 0:0.2:1;
            ax.Color = 'k';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.GridColor = [1 1 0];
            
            % Create wingPlot3D
            app.wingPlot3D = uiaxes(app.UIFigure);
            title(app.wingPlot3D, '3D wing')
            xlabel(app.wingPlot3D, 'X')
            ylabel(app.wingPlot3D, 'Y')
            app.wingPlot3D.PlotBoxAspectRatio = [1 0.719869706840391 0.719869706840391];
            app.wingPlot3D.XTick = [0 1];
            app.wingPlot3D.YTick = [0 1];
            app.wingPlot3D.Position = [1 5 318 276];
            ax=app.wingPlot3D;
            % clf(app.wingPlot3D, 'reset')
            ax.XLim = [0 10];
            ax.YLim = [0 1];
            ax.YDir = 'reverse';
            ax.ZLim = [-.5 .5];
            ax.XTick = 0:5:10;
            ax.YTick = 0:0.5:1;
            ax.ZTick = -.5:.5:.5;
            ax.Color = 'k';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.ZGrid = 'on';
            ax.GridColor = [1 1 0];            
            view(app.wingPlot3D, 3)
        end
        
        function [xyUpper, xyLower, xyCamber]=calculate_2d_profile(app, M, P, XX, numberOfPoints)
        initWithZeros = zeros(numberOfPoints, 1);
        %populating x values from 0->1 ca~camber array
        %init variables
        xCamber=linspace(0,1,numberOfPoints)';
        yCamber=initWithZeros;
        xyCamber=[xCamber yCamber];
        yHalfThickness=initWithZeros;
        xyUpper=initWithZeros;
        phi=initWithZeros;
        xyLower=initWithZeros;
        for i=1:1:numberOfPoints
            % derivative of yc
            dyc=initWithZeros;
    
            xi=xyCamber(i,1);
            yCamberi=0;
            % first: 0 <= x <= P
            if(0 <= xi && xi < P)
                yCamberi=(M/P^2)*((2*P*xi)-(xi*xi));
                dyc(i)=2*M/P^2*(P-xi);
            % second: P <= x <= numberOfPoints
            elseif (P <= xi && xi <= numberOfPoints)
                yCamberi=(M/(1-P)^2)*(1-(2*P)+(2*P*xi)-(xi*xi));
                dyc(i)=2*M/(1-P)^2*(P-xi);
            end
            xyCamber(i,2)=yCamberi;
            % calculate half-thickness at every x
            % a0=0.2969; a1=0.1260; a2=0.3516; a3=0.2843; a4=0.1015;
            yHalfThickness(i)=5*(XX/100)*(0.2969*sqrt(xi)-0.1260*xi-0.3516*xi^2+0.2843*xi^3-0.1015*xi^4);
            % calculate angle of deviation
            phi(i)=atan(dyc(i));
            % calculate upper and lower part of the airfoil
            % xUpper=x-yt*sin(phi) and xLower=x+yt*sin(phi)
            % yUpper=yc+yt*cos(phi) and yLower=yt-yt*cos(phi)
            xyUpper(i,1)=xi-yHalfThickness(i)*sin(phi(i)); xyUpper(i,2)=yCamberi+yHalfThickness(i)*cos(phi(i));
            xyLower(i,1)=xi+yHalfThickness(i)*sin(phi(i)); xyLower(i,2)=yCamberi-yHalfThickness(i)*cos(phi(i));
    
            % inverting the y values because we are generating a spoiler
            % xyU(i,2) = xyU(i,2)*(-1);
            % xyL(i,2) = xyL(i,2)*(-1);
            % cca(i,2) = cca(i,2)*(-1);
            end
        end

        % calculate edge points for given number of points
        % in the profile generator, these points would lie on the Z plane
        % ypoints should be in the 0->1 space
        % TODO: parametric function generation based on base and end of wing
        % zValues,xyUpper,xyLower,edgeFrontX,edgeBackX,P,wing_length,numberOfPoints
        function [edgeFront, edgeBack]=calculate_edges_of_wing(app, numberOfPoints, wing_length, P, startEndProportions)
            
            halfOfStartEndDiff = (1 - startEndProportions)/2;
            
            xpoints = linspace(0,wing_length,numberOfPoints);
            edgeFront = zeros(numberOfPoints,1);
            edgeBack = zeros(numberOfPoints,1);
            for i=1:1:numberOfPoints
                edgeFront(i) = 1 - halfOfStartEndDiff*(xpoints(i)/wing_length);
                edgeBack(i) = 0 + halfOfStartEndDiff*(xpoints(i)/wing_length);
            end
            %edgeFront = rescale(ypointsFront, 0,(1-P)); edgeBack = rescale(ypointsBack, 0, P);
            %for i=1:1:numberOfPoints
            %    edgeFront(i) = edgeFront(i)+P;
            %end    
        end
    
        function [xyUpperCurved, xyLowerCurved]=calculate_3d_wing_edged(app, zValues,xyUpper,xyLower,edgeFrontX,edgeBackX,P,wing_length,numberOfPoints)
            xyUpperCurved=zeros(numberOfPoints,numberOfPoints,2);
            xyLowerCurved=zeros(numberOfPoints,numberOfPoints,2);
            xyUpperTemp=xyUpper;
            xyLowerTemp=xyLower;
            
            for j=1:1:numberOfPoints
                for i=1:1:numberOfPoints
                    % If xyUx<P 
                    if(xyUpper(i,1)<P)
                        if(xyUpper(i,1)>0)
                            xyUpperTemp(i,1) = ((xyUpper(i,1) - (1-(xyUpper(i,1)/P))*edgeFrontX(j))*P) ;
                        else
                            xyUpperTemp(i,1) = ((xyUpper(i,1) - edgeFrontX(j))*P);
                        end
                    else
                        if(xyUpper(i,1)>0)
                            xyUpperTemp(i,1) = ((xyUpper(i,1) + (1-(xyUpper(i,1)/P))*edgeBackX(j))*P);
                        else
                            xyUpperTemp(i,1) = (((xyUpper(i,1) + edgeBackX(j)))*P);
                        end
                    end
                    % If xyL<P 
                    if(xyLower(i,1)<P)
                        if(xyLower(i,1)>0)
                            xyLowerTemp(i,1) = ((xyLower(i,1) - (1-(xyLower(i,1)/P))*edgeFrontX(j))*P);
                        else
                            xyLowerTemp(i,1) = ((xyLower(i,1) - edgeFrontX(j))*P);
                        end
                    else
                        if(xyUpper(i,1)>0)
                            xyLowerTemp(i,1) = ((((xyLower(i,1) + (1-(xyLower(i,1)/P))*edgeBackX(j))))*P);
                        else
                            xyLowerTemp(i,1) = ((xyLower(i,1) + edgeBackX(j))*P);
                        end
                    end
                    xyUpperTemp(i,1) = (xyUpperTemp(i,1)/P/2)+.5;
                    xyLowerTemp(i,1) = (xyLowerTemp(i,1)/P/2)+.5;
                    xyUpperCurved(j,i,1) = xyUpperTemp(i,1);
                    xyUpperCurved(j,i,2) = xyUpperTemp(i,2);
                    xyLowerCurved(j,i,1) = xyLowerTemp(i,1);
                    xyLowerCurved(j,i,2) = xyLowerTemp(i,2);
                end
            end
        end
        
        function []=plot_2d_profile(app, xyCamber,xyUpper, xyLower, numberOfPoints)
            hold(app.wingPlotCamber)
            plot(app.wingPlotCamber, xyCamber(1:numberOfPoints,1), xyCamber(1:numberOfPoints,2), 'r')
            plot(app.wingPlotCamber, xyUpper(1:numberOfPoints,1), xyUpper(1:numberOfPoints,2), 'g')
            plot(app.wingPlotCamber, xyLower(1:numberOfPoints,1), xyLower(1:numberOfPoints,2), 'g')
        end
        
        function []=plot_edges_of_wing(app, zValues, edgeFrontX, edgeBackX, numberOfPoints)
            hold(app.wingPlotEdge)
            plot(app.wingPlotEdge, zValues(1:numberOfPoints), edgeFrontX(1:numberOfPoints));
            plot(app.wingPlotEdge, zValues(1:numberOfPoints), edgeBackX(1:numberOfPoints));
        end
        
        function []=plot_3d_wing_edged(app, zValues,xyUpperCurved,xyLowerCurved,wing_length,numberOfPoints)
            hold(app.wingPlot3D)
            for j=1:1:numberOfPoints
                for i=1:1:numberOfPoints
                    plot3(app.wingPlot3D, zValues(j), xyUpperCurved(j,i,1), xyUpperCurved(j,i,2),'.', 'Color', [0 1 0])
                    plot3(app.wingPlot3D, zValues(j), xyLowerCurved(j,i,1), xyLowerCurved(j,i,2),'.', 'Color', [1 0 0])
                end
            end
        end
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initPlots(app)
        end

        % Button pushed function: calculatewingButton
        function calculatewingButtonPushed(app, event)
            initPlots(app)
            % NACA 4 digit airfoil camber/
            % MPXX
            M=app.mEditField.Value;
            P=app.pEditField.Value;
            XX=app.xxEditField.Value;
            M=(M/100);
            P=(P/10);
            endStartProportions = app.endEditField.Value;
            
            % number of points the space is divided for the modeling
            numberOfPoints=app.numberOfPointsEditField.Value;
            initWithZeros=zeros(numberOfPoints, 1);
            
            % function to calculate below parameters
            [xyUpper, xyLower, xyCamber]=calculate_2d_profile(app, M, P, XX, numberOfPoints);
            
            % plotting 2d profile
            plot_2d_profile(app, xyCamber,xyUpper, xyLower, numberOfPoints);
            
            % plotting profile in 3d with points
            wing_length=10;
            zValues=linspace(0,wing_length,numberOfPoints);
            
            % edgeFrontX and edgeBackX is one dimensional, contains X values for wing
            % curve
            [edgeFrontX,edgeBackX]=calculate_edges_of_wing(app, numberOfPoints, wing_length, P, endStartProportions);
            
            plot_edges_of_wing(app, zValues, edgeFrontX, edgeBackX, numberOfPoints)
            
            [xyUpperCurved, xyLowerCurved]=calculate_3d_wing_edged(app, zValues,xyUpper,xyLower,edgeFrontX,edgeBackX,P,wing_length,numberOfPoints);
            
            plot_3d_wing_edged(app, zValues,xyUpperCurved,xyLowerCurved,wing_length,numberOfPoints);
            view(app.wingPlot3D, 3)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditField.Value = 2;
            app.camberpropertiesLabel = uilabel(app.UIFigure);
            app.camberpropertiesLabel.Text = 'Camber properties';
            app.wingendproportionsLabel = uilabel(app.UIFigure);
            app.wingendproportionsLabel.Text = 'Wing end proportions';
            app.PEditField_2Label = uilabel(app.UIFigure);
            app.PEditField_2Label.Text = 'P';
            app.pEditField = uieditfield(app.UIFigure, 'numeric');
            app.pEditField.Value = 4;
            app.XXEditField_2Label = uilabel(app.UIFigure);
            app.XXEditField_2Label.Text = 'XX';
            app.xxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xxEditField.Value = 12;
            app.calculatewingButton = uibutton(app.UIFigure, 'push');
            app.calculatewingButton.ButtonPushedFcn = createCallbackFcn(app, @calculatewingButtonPushed, true);
            app.calculatewingButton.Text = 'Calculate wing';
            app.wingPlot3D = uiaxes(app.UIFigure);
            title(app.wingPlot3D, '3D wing')
            xlabel(app.wingPlot3D, 'X')
            ylabel(app.wingPlot3D, 'Y')
            app.wingPlot3D.XTick = [0 1];
            app.wingPlot3D.YTick = [0 1];
            app.EndStartLabel = uilabel(app.UIFigure);
            app.EndStartLabel.Text = 'End/Start';
            app.endEditField = uieditfield(app.UIFigure, 'numeric');
            app.endEditField.Value = 0.5;
            app.wingPlotCamber = uiaxes(app.UIFigure);
            title(app.wingPlotCamber, 'Camber')
            xlabel(app.wingPlotCamber, 'X')
            ylabel(app.wingPlotCamber, 'Y')
            app.wingPlotCamber.XTick = [];
            app.wingPlotCamber.YTick = [];
            app.wingPlotEdge = uiaxes(app.UIFigure);
            title(app.wingPlotEdge, 'Edge')
            xlabel(app.wingPlotEdge, 'X')
            ylabel(app.wingPlotEdge, 'Y')
            app.NACA4digitwinggeneratorLabel = uilabel(app.UIFigure);
            app.NACA4digitwinggeneratorLabel.Text = 'NACA 4-digit wing generator';
            app.NumberofpointsLabel = uilabel(app.UIFigure);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.numberOfPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.numberOfPointsEditField.Value = 21;
            app.CalculationdensityLabel = uilabel(app.UIFigure);
            app.CalculationdensityLabel.Text = 'Calculation density';
        end
    end

    methods (Access = public)

        % Construct app
        function app = wing_profile

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end